classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        OriginalaudiosignalPanel  matlab.ui.container.Panel
        PlayOriginalButton        matlab.ui.control.Button
        OriginalSignalAxes        matlab.ui.control.UIAxes
        OriginalSpectrumAxes      matlab.ui.control.UIAxes
        FilteredaudiosignalPanel  matlab.ui.container.Panel
        PlayFilteredButton        matlab.ui.control.Button
        FilteredSignalAxes        matlab.ui.control.UIAxes
        FilteredSpectrumAxes      matlab.ui.control.UIAxes
        GridLayout2               matlab.ui.container.GridLayout
        FrequencyresponsePanel    matlab.ui.container.Panel
        FilterResponseAxes        matlab.ui.control.UIAxes
        LoadandResetPanel         matlab.ui.container.Panel
        ResetButton               matlab.ui.control.Button
        LoadaudiofileButton       matlab.ui.control.Button
        EqualizerPanel            matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        Slider_5                  matlab.ui.control.Slider
        Slider_5Label             matlab.ui.control.Label
        Slider_4                  matlab.ui.control.Slider
        Slider_4Label             matlab.ui.control.Label
        Slider_3                  matlab.ui.control.Slider
        Slider_3Label             matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        Slider_2Label             matlab.ui.control.Label
        Slider_1                  matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        audioSignal = [];
        audioSignalFiltered = [];
        samplingFreq = [];
        b = cell(1,5);
        specRange;
    end
    
    methods (Access = private)
        
        function calculateFilter(app)
            sliderValue = [
                app.Slider_1.Value, ...
                app.Slider_2.Value, ...
                app.Slider_3.Value, ...
                app.Slider_4.Value, ...
                app.Slider_5.Value];

            H = zeros([512, 5]);
            w = [];
            for i = 1:5
                [Hi, w] = freqz(app.b{i}, 1);
                H(:, i) = 10 .^ sliderValue(i) .* Hi;
            end
            H = sum(H, 2);            
           
            if isempty(app.audioSignal) || isempty(app.samplingFreq)
                plot(app.FilterResponseAxes, 20 * log10(abs(H)));
                xlim(app.FilterResponseAxes, [0 length(w)]);
                return;
            end

            fHz = w / pi * (app.samplingFreq/2);
            plot(app.FilterResponseAxes, fHz, 20 * log10(abs(H)));
            xlim(app.FilterResponseAxes, [0 fHz(end)]);
            xlabel(app.FilterResponseAxes, 'Frequency [Hz]');
    
            y = zeros(length(app.audioSignal), 5);
            for i = 1:5
                y(:, i) = 10 .^ sliderValue(i) .* filter(app.b{i}, 1, app.audioSignal);
            end
            y = sum(y, 2);  
            m = max(abs(y));
            if m > 1
                app.audioSignalFiltered = y / m;
            else
                app.audioSignalFiltered = y;
            end
    
            t = (0:length(app.audioSignalFiltered) - 1) / app.samplingFreq;
            plot(app.FilteredSignalAxes, t, app.audioSignalFiltered);
            xlabel(app.FilteredSignalAxes, 'Time [s]');
            ylabel(app.FilteredSignalAxes, 'Amplitude');
            xlim(app.FilteredSignalAxes, [0 t(end)]);
    
            N = 2^nextpow2(20e-3 * app.samplingFreq);
            L = round(N/2);
            [spectrum, freq, time] = spectrogram(app.audioSignalFiltered, N, L, N, app.samplingFreq);

            imagesc(app.FilteredSpectrumAxes, time, freq, log(abs(spectrum) + eps));

            axis(app.FilteredSpectrumAxes, 'xy');
            xlabel(app.FilteredSpectrumAxes, 'Time [s]');
            ylabel(app.FilteredSpectrumAxes, 'Frequency [Hz]');
            xlim(app.FilteredSpectrumAxes, [0 t(end)]);
            ylim(app.FilteredSpectrumAxes, [0 app.samplingFreq/2]);
            clim(app.FilteredSpectrumAxes, app.specRange);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            n = 200;
            d  = linspace(log10(1), log10(6), 6);
            Ws = (10.^d - 1) / 5;
        
            app.b{1} = fir1(n, Ws(2), 'low');
            app.b{2} = fir1(n, [Ws(2) Ws(3)]);
            app.b{3} = fir1(n, [Ws(3) Ws(4)]);
            app.b{4} = fir1(n, [Ws(4) Ws(5)]);
            app.b{5} = fir1(n, Ws(5), 'high');
        
            calculateFilter(app);
        end

        % Button pushed function: LoadaudiofileButton
        function LoadaudiofileButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.wav');

            if isequal(FileName, 0)
                msgbox('No file was selected');
                return;
            end
        
            fullPath = fullfile(PathName, FileName);
            try
                [app.audioSignal, app.samplingFreq] = audioread(fullPath);
            catch
                msgbox('The selected file could not be loaded. Please select a valid .wav file.');
                return;
            end

            if size(app.audioSignal, 2) > 1
                app.audioSignal = mean(app.audioSignal, 2);
            end

            t = (0:length(app.audioSignal) - 1) / app.samplingFreq;

            plot(app.OriginalSignalAxes, t, app.audioSignal);

            xlabel(app.OriginalSignalAxes, 'Time [s]');
            ylabel(app.OriginalSignalAxes, 'Amplitude');
            xlim(app.OriginalSignalAxes, [0 t(end)]);

            N = 2^nextpow2(20e-3 * app.samplingFreq);
            L = round(N/2);
            [spectrum, freq, time] = spectrogram(app.audioSignal, N, L, N, app.samplingFreq);
            
            imagesc(app.OriginalSpectrumAxes, time, freq, log(abs(spectrum) + eps));

            axis(app.OriginalSpectrumAxes, 'xy');
            xlabel(app.OriginalSpectrumAxes, 'Time [s]');
            ylabel(app.OriginalSpectrumAxes, 'Frequency [Hz]');
            xlim(app.OriginalSpectrumAxes, [0 t(end)]);
            ylim(app.OriginalSpectrumAxes, [0 app.samplingFreq/2]);
            app.specRange = clim(app.OriginalSpectrumAxes);

            calculateFilter(app);
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            if isempty(app.audioSignal)
                msgbox('Please load an audio file first.');
                return;
            end
        
            sound(app.audioSignal, app.samplingFreq);
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
            if isempty(app.audioSignal)
                msgbox('Please load an audio file first.');
                return;
            end
        
            sound(app.audioSignalFiltered, app.samplingFreq);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Slider_1.Value = 0;
            app.Slider_2.Value = 0;
            app.Slider_3.Value = 0;
            app.Slider_4.Value = 0;
            app.Slider_5.Value = 0;
            calculateFilter(app);
        end

        % Value changed function: Slider_1, Slider_2, Slider_3, Slider_4, 
        % ...and 1 other component
        function SlidersValueChanged(app, event)
            calculateFilter(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EqualizerPanel = uipanel(app.GridLayout);
            app.EqualizerPanel.Title = 'Equalizer';
            app.GridLayout3 = uigridlayout(app.EqualizerPanel);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Slider_1 = uislider(app.GridLayout3);
            app.Slider_1.Limits = [-1 1];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @SlidersValueChanged, true);
            app.Slider_2Label = uilabel(app.GridLayout3);
            app.Slider_2Label.Text = '';
            app.Slider_2 = uislider(app.GridLayout3);
            app.Slider_2.Limits = [-1 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SlidersValueChanged, true);
            app.Slider_3Label = uilabel(app.GridLayout3);
            app.Slider_3Label.Text = '';
            app.Slider_3 = uislider(app.GridLayout3);
            app.Slider_3.Limits = [-1 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SlidersValueChanged, true);
            app.Slider_4Label = uilabel(app.GridLayout3);
            app.Slider_4Label.Text = '';
            app.Slider_4 = uislider(app.GridLayout3);
            app.Slider_4.Limits = [-1 1];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @SlidersValueChanged, true);
            app.Slider_5Label = uilabel(app.GridLayout3);
            app.Slider_5Label.Text = '';
            app.Slider_5 = uislider(app.GridLayout3);
            app.Slider_5.Limits = [-1 1];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @SlidersValueChanged, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadandResetPanel = uipanel(app.GridLayout2);
            app.LoadandResetPanel.Title = 'Load and Reset';
            app.LoadaudiofileButton = uibutton(app.LoadandResetPanel, 'push');
            app.LoadaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaudiofileButtonPushed, true);
            app.LoadaudiofileButton.Text = 'Load audio file';
            app.ResetButton = uibutton(app.LoadandResetPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FrequencyresponsePanel = uipanel(app.GridLayout2);
            app.FrequencyresponsePanel.Title = 'Frequency response';
            app.FilterResponseAxes = uiaxes(app.FrequencyresponsePanel);
            title(app.FilterResponseAxes, 'Frequency Response')
            xlabel(app.FilterResponseAxes, 'Samples')
            ylabel(app.FilterResponseAxes, 'Magnitude [dB]')
            zlabel(app.FilterResponseAxes, 'Z')
            app.FilterResponseAxes.YLim = [-25 25];
            app.FilteredaudiosignalPanel = uipanel(app.GridLayout);
            app.FilteredaudiosignalPanel.Title = 'Filtered audio signal';
            app.FilteredSpectrumAxes = uiaxes(app.FilteredaudiosignalPanel);
            title(app.FilteredSpectrumAxes, 'Filtered Spectrum')
            xlabel(app.FilteredSpectrumAxes, 'Time [s]')
            ylabel(app.FilteredSpectrumAxes, 'Frequency [Hz]')
            zlabel(app.FilteredSpectrumAxes, 'Z')
            app.FilteredSignalAxes = uiaxes(app.FilteredaudiosignalPanel);
            title(app.FilteredSignalAxes, 'Filtered Signal')
            xlabel(app.FilteredSignalAxes, 'Time [s]')
            ylabel(app.FilteredSignalAxes, 'Amplitude')
            zlabel(app.FilteredSignalAxes, 'Z')
            app.FilteredSignalAxes.YLim = [-1 1];
            app.PlayFilteredButton = uibutton(app.FilteredaudiosignalPanel, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = 'Play';
            app.OriginalaudiosignalPanel = uipanel(app.GridLayout);
            app.OriginalaudiosignalPanel.Title = 'Original audio signal';
            app.OriginalSpectrumAxes = uiaxes(app.OriginalaudiosignalPanel);
            title(app.OriginalSpectrumAxes, 'Original Spectrum')
            xlabel(app.OriginalSpectrumAxes, 'Time [s]')
            ylabel(app.OriginalSpectrumAxes, 'Frequency [Hz]')
            zlabel(app.OriginalSpectrumAxes, 'Z')
            app.OriginalSignalAxes = uiaxes(app.OriginalaudiosignalPanel);
            title(app.OriginalSignalAxes, 'Original Signal')
            xlabel(app.OriginalSignalAxes, 'Time [s]')
            ylabel(app.OriginalSignalAxes, 'Amplitude')
            zlabel(app.OriginalSignalAxes, 'Z')
            app.OriginalSignalAxes.YLim = [-1 1];
            app.PlayOriginalButton = uibutton(app.OriginalaudiosignalPanel, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end