classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        OriginalaudiosignalPanel  matlab.ui.container.Panel
        PlayOriginalButton        matlab.ui.control.Button
        OriginalSignalAxes        matlab.ui.control.UIAxes
        OriginalSpectrumAxes      matlab.ui.control.UIAxes
        FilteredaudiosignalPanel  matlab.ui.container.Panel
        PlayFilteredButton        matlab.ui.control.Button
        FilteredSignalAxes        matlab.ui.control.UIAxes
        FilteredSpectrumAxes      matlab.ui.control.UIAxes
        GridLayout2               matlab.ui.container.GridLayout
        FrequencyresponsePanel    matlab.ui.container.Panel
        FilterResponseAxes        matlab.ui.control.UIAxes
        LoadandResetPanel         matlab.ui.container.Panel
        ResetButton               matlab.ui.control.Button
        LoadaudiofileButton       matlab.ui.control.Button
        EqualizerPanel            matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        Slider                    matlab.ui.control.Slider
        Slider_2                  matlab.ui.control.Slider
        Slider_3                  matlab.ui.control.Slider
        Slider_4                  matlab.ui.control.Slider
        Slider_5                  matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        audioSignal;
        samplingFreq;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadaudiofileButton
        function LoadaudiofileButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.wav');

            if isequal(FileName, 0)
                msgbox('No file was selected');
                return;
            end
        
            fullPath = fullfile(PathName, FileName);
            try
                [app.audioSignal, app.samplingFreq] = audioread(fullPath);
            catch
                msgbox('The selected file could not be loaded. Please select a valid .wav file.');
                return;
            end

            if size(app.audioSignal, 2) > 1
                app.audioSignal = mean(app.audioSignal, 2);
            end

            t = (0:length(app.audioSignal) - 1) / app.samplingFreq;

            plot(app.OriginalSignalAxes, t, app.audioSignal);

            xlabel(app.OriginalSignalAxes, 'Time [s]');
            ylabel(app.OriginalSignalAxes, 'Amplitude');
            xlim(app.OriginalSignalAxes, [0 t(end)]);

            N = 2^nextpow2(20e-3 * app.samplingFreq);
            L = round(N/2);
            [spectrum, freq, time] = spectrogram(app.audioSignal, N, L, N, app.samplingFreq);
            
            imagesc(app.OriginalSpectrumAxes, time, freq, log(abs(spectrum) + eps));

            axis(app.OriginalSpectrumAxes, 'xy');
            xlabel(app.OriginalSpectrumAxes, 'Time [s]');
            ylabel(app.OriginalSpectrumAxes, 'Frequency [Hz]');
            xlim(app.OriginalSpectrumAxes, [0 t(end)]);
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            if isempty(app.audioSignal)
                msgbox('Please load an audio file first.');
                return;
            end
        
            sound(app.audioSignal, app.samplingFreq);
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
            msgbox('Filtered signal is not implemented yet.');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.OriginalSignalAxes);
            cla(app.OriginalSpectrumAxes);
            cla(app.FilteredSignalAxes);
            cla(app.FilteredSpectrumAxes);
            cla(app.FilterResponseAxes);
        
            app.audioSignal = [];
            app.samplingFreq = [];
        
            app.Slider.Value = 0;
            app.Slider_2.Value = 0;
            app.Slider_3.Value = 0;
            app.Slider_4.Value = 0;
            app.Slider_5.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EqualizerPanel = uipanel(app.GridLayout);
            app.EqualizerPanel.Title = 'Equalizer';
            app.GridLayout3 = uigridlayout(app.EqualizerPanel);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Slider_5 = uislider(app.GridLayout3);
            app.Slider_4 = uislider(app.GridLayout3);
            app.Slider_3 = uislider(app.GridLayout3);
            app.Slider_2 = uislider(app.GridLayout3);
            app.Slider = uislider(app.GridLayout3);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadandResetPanel = uipanel(app.GridLayout2);
            app.LoadandResetPanel.Title = 'Load and Reset';
            app.LoadaudiofileButton = uibutton(app.LoadandResetPanel, 'push');
            app.LoadaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaudiofileButtonPushed, true);
            app.LoadaudiofileButton.Text = 'Load audio file';
            app.ResetButton = uibutton(app.LoadandResetPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FrequencyresponsePanel = uipanel(app.GridLayout2);
            app.FrequencyresponsePanel.Title = 'Frequency response';
            app.FilterResponseAxes = uiaxes(app.FrequencyresponsePanel);
            title(app.FilterResponseAxes, 'Frequency Response')
            xlabel(app.FilterResponseAxes, 'Frequency [Hz]')
            ylabel(app.FilterResponseAxes, 'Magnitude')
            zlabel(app.FilterResponseAxes, 'Z')
            app.FilteredaudiosignalPanel = uipanel(app.GridLayout);
            app.FilteredaudiosignalPanel.Title = 'Filtered audio signal';
            app.FilteredSpectrumAxes = uiaxes(app.FilteredaudiosignalPanel);
            title(app.FilteredSpectrumAxes, 'Filtered Spectrum')
            xlabel(app.FilteredSpectrumAxes, 'Time [s]')
            ylabel(app.FilteredSpectrumAxes, 'Frequency [Hz]')
            zlabel(app.FilteredSpectrumAxes, 'Z')
            app.FilteredSignalAxes = uiaxes(app.FilteredaudiosignalPanel);
            title(app.FilteredSignalAxes, 'Filtered Signal')
            xlabel(app.FilteredSignalAxes, 'Time [s]')
            ylabel(app.FilteredSignalAxes, 'Amplitude')
            zlabel(app.FilteredSignalAxes, 'Z')
            app.PlayFilteredButton = uibutton(app.FilteredaudiosignalPanel, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = 'Play';
            app.OriginalaudiosignalPanel = uipanel(app.GridLayout);
            app.OriginalaudiosignalPanel.Title = 'Original audio signal';
            app.OriginalSpectrumAxes = uiaxes(app.OriginalaudiosignalPanel);
            title(app.OriginalSpectrumAxes, 'Original Spectrum')
            xlabel(app.OriginalSpectrumAxes, 'Time [s]')
            ylabel(app.OriginalSpectrumAxes, 'Frequency [Hz]')
            zlabel(app.OriginalSpectrumAxes, 'Z')
            app.OriginalSignalAxes = uiaxes(app.OriginalaudiosignalPanel);
            title(app.OriginalSignalAxes, 'Original Signal')
            xlabel(app.OriginalSignalAxes, 'Time [s]')
            ylabel(app.OriginalSignalAxes, 'Amplitude')
            zlabel(app.OriginalSignalAxes, 'Z')
            app.PlayOriginalButton = uibutton(app.OriginalaudiosignalPanel, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end